classdef TADSISPD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ModelTab                        matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        ZarzdzanieTab                   matlab.ui.container.Tab
        WczytajdanerzeczywisteButton    matlab.ui.control.Button
        WykonajsymulacjButton           matlab.ui.control.Button
        ZapiszwynikidoplikwButton       matlab.ui.control.Button
        b                               matlab.ui.control.NumericEditField
        a                               matlab.ui.control.NumericEditField
        beta                            matlab.ui.control.NumericEditField
        alfa                            matlab.ui.control.NumericEditField
        c                               matlab.ui.control.NumericEditField
        gamma                           matlab.ui.control.NumericEditField
        d                               matlab.ui.control.NumericEditField
        lambda                          matlab.ui.control.NumericEditField
        o1                              matlab.ui.control.NumericEditField
        f1                              matlab.ui.control.NumericEditField
        TypeinratesoftransitiontoeachstateLabel  matlab.ui.control.Label
        TypeintheinitialnumberofpeopleineachstateLabel  matlab.ui.control.Label
        NosecuritymeasuresLabel         matlab.ui.control.Label
        NSM0                            matlab.ui.control.NumericEditField
        SelfprotectingLabel_2           matlab.ui.control.Label
        SP0                             matlab.ui.control.NumericEditField
        InfectedLabel_2                 matlab.ui.control.Label
        INF0                            matlab.ui.control.NumericEditField
        InfectedandsickLabel_2          matlab.ui.control.Label
        IAS0                            matlab.ui.control.NumericEditField
        RecoveredLabel                  matlab.ui.control.Label
        REC0                            matlab.ui.control.NumericEditField
        QuarantineLabel                 matlab.ui.control.Label
        Q0                              matlab.ui.control.NumericEditField
        InhospitalLabel                 matlab.ui.control.Label
        INH0                            matlab.ui.control.NumericEditField
        DeadLabel                       matlab.ui.control.Label
        D0                              matlab.ui.control.NumericEditField
        HealthyNosecuritymeasuresLabel  matlab.ui.control.Label
        HealthySelfprotectingLabel      matlab.ui.control.Label
        SelfprotectingInfectedLabel     matlab.ui.control.Label
        NosecuritymeasuresInfectedLabel  matlab.ui.control.Label
        InfectedQuarantineLabel         matlab.ui.control.Label
        InfectedInfectedandsickLabel    matlab.ui.control.Label
        InfectedandsickQuarantineLabel  matlab.ui.control.Label
        InfectedandsickInhospitalLabel  matlab.ui.control.Label
        InhospitalRecoveredLabel        matlab.ui.control.Label
        QuarantineRecoveredLabel        matlab.ui.control.Label
        InhospitalDeadLabel             matlab.ui.control.Label
        TypeinpopulationLabel           matlab.ui.control.Label
        populationModel                 matlab.ui.control.NumericEditField
        l1                              matlab.ui.control.NumericEditField
        l2                              matlab.ui.control.NumericEditField
        l3                              matlab.ui.control.NumericEditField
        o2                              matlab.ui.control.NumericEditField
        o3                              matlab.ui.control.NumericEditField
        f2                              matlab.ui.control.NumericEditField
        f3                              matlab.ui.control.NumericEditField
        TestrateLabel_2                 matlab.ui.control.Label
        ModelTestRate                   matlab.ui.control.NumericEditField
        TypeintestrateLabel             matlab.ui.control.Label
        WyczywykresyButton              matlab.ui.control.Button
        ModelWykresy                    matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        ModelTabNSM                     matlab.ui.container.Tab
        AxesModelNSM                    matlab.ui.control.UIAxes
        ModelTabSP                      matlab.ui.container.Tab
        AxesModelSP                     matlab.ui.control.UIAxes
        ModelTabINF                     matlab.ui.container.Tab
        AxesModelINF                    matlab.ui.control.UIAxes
        ModelTabIAS                     matlab.ui.container.Tab
        AxesModelIAS                    matlab.ui.control.UIAxes
        ModelTabQ                       matlab.ui.container.Tab
        AxesModelQ                      matlab.ui.control.UIAxes
        ModelTabINH                     matlab.ui.container.Tab
        AxesModelINH                    matlab.ui.control.UIAxes
        ModelTabTotalINF                matlab.ui.container.Tab
        AxesModelTotalINF               matlab.ui.control.UIAxes
        ModelTabREC                     matlab.ui.container.Tab
        AxesModelREC                    matlab.ui.control.UIAxes
        ModelTabD                       matlab.ui.container.Tab
        AxesModelD                      matlab.ui.control.UIAxes
        SymulacjeTab                    matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        NorwegiaTab                     matlab.ui.container.Tab
        PlaneSizeEditFieldLabel         matlab.ui.control.Label
        PlaneSizeEditField              matlab.ui.control.NumericEditField
        NumberOfSimulationsEditFieldLabel  matlab.ui.control.Label
        NumberOfSimulationsEditField    matlab.ui.control.NumericEditField
        NumberOfSimulationStepsEditFieldLabel  matlab.ui.control.Label
        NumberOfSimulationStepsEditField  matlab.ui.control.NumericEditField
        ChanceToGetSickEditFieldLabel   matlab.ui.control.Label
        ChanceToGetSickEditField        matlab.ui.control.NumericEditField
        AverageIncubationEditFieldLabel  matlab.ui.control.Label
        AverageIncubationEditField      matlab.ui.control.NumericEditField
        SigmaIncubationEditFieldLabel   matlab.ui.control.Label
        SigmaIncubationEditField        matlab.ui.control.NumericEditField
        AverageDamageEditFieldLabel     matlab.ui.control.Label
        AverageDamageEditField          matlab.ui.control.NumericEditField
        SigmaDamageEditFieldLabel       matlab.ui.control.Label
        SigmaDamageEditField            matlab.ui.control.NumericEditField
        StartHealthEditFieldLabel       matlab.ui.control.Label
        StartHealthEditField            matlab.ui.control.NumericEditField
        HealthCareLimitEditFieldLabel   matlab.ui.control.Label
        HealthCareLimitEditField        matlab.ui.control.NumericEditField
        ChanceToGoToHospitalByYourselfEditFieldLabel  matlab.ui.control.Label
        ChanceToGoToHospitalByYourselfEditField  matlab.ui.control.NumericEditField
        StartTestedProcentEditFieldLabel  matlab.ui.control.Label
        StartTestedProcentEditField     matlab.ui.control.NumericEditField
        TestIncomPerDayEditFieldLabel   matlab.ui.control.Label
        TestIncomPerDayEditField        matlab.ui.control.NumericEditField
        TestSensitivityEditFieldLabel   matlab.ui.control.Label
        TestSensitivityEditField        matlab.ui.control.NumericEditField
        ProtectingMultEditFieldLabel    matlab.ui.control.Label
        ProtectingMultEditField         matlab.ui.control.NumericEditField
        OrganizingMultEditFieldLabel    matlab.ui.control.Label
        OrganizingMultEditField         matlab.ui.control.NumericEditField
        ChanceToGetSickAgainMulEditFieldLabel  matlab.ui.control.Label
        ChanceToGetSickAgainMulEditField  matlab.ui.control.NumericEditField
        RunSimulationButton             matlab.ui.control.Button
        FrancjaTab                      matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        ParametrysymulacjiTab           matlab.ui.container.Tab
        SizeEditFieldLabel              matlab.ui.control.Label
        FranceSize                      matlab.ui.control.NumericEditField
        DaysEditFieldLabel              matlab.ui.control.Label
        FranceDays                      matlab.ui.control.NumericEditField
        TestrateLabel                   matlab.ui.control.Label
        FranceTestRate                  matlab.ui.control.NumericEditField
        FranceStartSimulation           matlab.ui.control.Button
        ProbabilityofinfectionLabel     matlab.ui.control.Label
        FranceProbabilityOfInfection    matlab.ui.control.NumericEditField
        NumberofPatientsZeroEditFieldLabel  matlab.ui.control.Label
        FranceNumberOfPatientsZero      matlab.ui.control.NumericEditField
        SeriouscasesEditFieldLabel      matlab.ui.control.Label
        FranceSeriousCases              matlab.ui.control.NumericEditField
        AveragefatalityrateEditFieldLabel  matlab.ui.control.Label
        FranceFatalityRate              matlab.ui.control.NumericEditField
        FranceStop                      matlab.ui.control.CheckBox
        FranceClear                     matlab.ui.control.Button
        Q1Tab                           matlab.ui.container.Tab
        AxesFranceQ1                    matlab.ui.control.UIAxes
        Q2Tab                           matlab.ui.container.Tab
        AxesFranceQ2                    matlab.ui.control.UIAxes
        WykresyTab                      matlab.ui.container.Tab
        AxesFrance1                     matlab.ui.control.UIAxes
        AxesFrance2                     matlab.ui.control.UIAxes
        AxesFrance3                     matlab.ui.control.UIAxes
        AxesFrance4                     matlab.ui.control.UIAxes
        AustraliaTab                    matlab.ui.container.Tab
        StartButtonAustralia            matlab.ui.control.Button
        SizeofplaneLabel                matlab.ui.control.Label
        SizeEditAustralia               matlab.ui.control.NumericEditField
        DaysEditFieldLabel_2            matlab.ui.control.Label
        DaysEditAustralia               matlab.ui.control.NumericEditField
        AxesAustralia                   matlab.ui.control.UIAxes
        TypeinLabel                     matlab.ui.control.Label
        madeTests                       matlab.ui.control.NumericEditField
        ResultsLabel                    matlab.ui.control.Label
        NumberodtestsdoneLabel          matlab.ui.control.Label
        NumberofpositivetestsLabel      matlab.ui.control.Label
        positiveTests                   matlab.ui.control.NumericEditField
        NumberoffallinfectedpeopleLabel  matlab.ui.control.Label
        numInfected                     matlab.ui.control.NumericEditField
        numRec                          matlab.ui.control.NumericEditField
        numDead                         matlab.ui.control.NumericEditField
        NumberofrecoveredpeopleLabel    matlab.ui.control.Label
        NumberofdeadpeopleLabel         matlab.ui.control.Label
        USATab                          matlab.ui.container.Tab
        TabGroup3_3                     matlab.ui.container.TabGroup
        MainstatsTab                    matlab.ui.container.Tab
        StartButton                     matlab.ui.control.Button
        LiczbacykliUSAEditFieldLabel    matlab.ui.control.Label
        LiczbacykliUSAEditField         matlab.ui.control.NumericEditField
        ZdrowiLabel                     matlab.ui.control.Label
        ZdrowiEditField                 matlab.ui.control.NumericEditField
        ChorzyEditFieldLabel            matlab.ui.control.Label
        ChorzyEditField                 matlab.ui.control.NumericEditField
        WyzdrowialiEditFieldLabel       matlab.ui.control.Label
        WyzdrowialiEditField            matlab.ui.control.NumericEditField
        ZmarliEditFieldLabel            matlab.ui.control.Label
        ZmarliEditField                 matlab.ui.control.NumericEditField
        ACTIVECASESLabel_2              matlab.ui.control.Label
        HEALTHLabel_2                   matlab.ui.control.Label
        RECOVEREDLabel_2                matlab.ui.control.Label
        DEADLabel_2                     matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        PorwnaniemodeliNorwegiaTab      matlab.ui.container.Tab
        TabGroup3_2                     matlab.ui.container.TabGroup
        RecoveredTab_2                  matlab.ui.container.Tab
        NorwayLabel                     matlab.ui.control.Label
        CAModelLabel                    matlab.ui.control.Label
        DifferentialModelLabel          matlab.ui.control.Label
        CArecnum                        matlab.ui.control.UIAxes
        Diffrecnum                      matlab.ui.control.UIAxes
        DeathsTab                       matlab.ui.container.Tab
        NorwayLabel_2                   matlab.ui.control.Label
        CAModelLabel_2                  matlab.ui.control.Label
        DifferentialModelLabel_2        matlab.ui.control.Label
        CAdeathsnum                     matlab.ui.control.UIAxes
        Diffdeathsnum                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FranceStartSimulation
        function runSimFrance(app, event)
            close all;
            SimulateFun(app);
        end

        % Callback function: FranceDays, WykonajsymulacjButton
        function runModelSim(app, event)
            simulation_real(app);
        end

        % Button pushed function: StartButtonAustralia
        function StartButtonAustraliaPushed(app, event)
            Symulacja_Koronawirusa(app);
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            simulationMain(app);
        end

        % Button pushed function: FranceClear
        function FranceClearAreas(app, event)
            cla(app.AxesFranceQ1);
            cla(app.AxesFranceQ2);
            cla(app.AxesFrance1);
            cla(app.AxesFrance2);
            cla(app.AxesFrance3);
            cla(app.AxesFrance4);
        end

        % Button pushed function: WyczywykresyButton
        function clearModelAxes(app, event)
            cla(app.AxesModelNSM);
            cla(app.AxesModelSP);
            cla(app.AxesModelINF);
            cla(app.AxesModelIAS);
            cla(app.AxesModelQ);
            cla(app.AxesModelINH);
            cla(app.AxesModelTotalINF);
            cla(app.AxesModelREC);
            cla(app.AxesModelD);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            main(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.TabGroup4 = uitabgroup(app.ModelTab);
            app.ZarzdzanieTab = uitab(app.TabGroup4);
            app.ZarzdzanieTab.Title = 'Zarządzanie';
            app.WczytajdanerzeczywisteButton = uibutton(app.ZarzdzanieTab, 'push');
            app.WczytajdanerzeczywisteButton.Text = 'Wczytaj dane rzeczywiste';
            app.WykonajsymulacjButton = uibutton(app.ZarzdzanieTab, 'push');
            app.WykonajsymulacjButton.ButtonPushedFcn = createCallbackFcn(app, @runModelSim, true);
            app.WykonajsymulacjButton.Text = 'Wykonaj symulację';
            app.ZapiszwynikidoplikwButton = uibutton(app.ZarzdzanieTab, 'push');
            app.ZapiszwynikidoplikwButton.Text = 'Zapisz wyniki do plików';
            app.b = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.b.Value = 0.1;
            app.a = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.a.Value = 0.4;
            app.beta = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.beta.Value = 0.5;
            app.alfa = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.alfa.Value = 0.9;
            app.c = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.c.Value = 0.12;
            app.gamma = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.gamma.Value = 0.2;
            app.d = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.d.Value = 0.25;
            app.lambda = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.lambda.Value = 0.05;
            app.o1 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.o1.Value = 0.01;
            app.f1 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.f1.Value = 0.001;
            app.TypeinratesoftransitiontoeachstateLabel = uilabel(app.ZarzdzanieTab);
            app.TypeinratesoftransitiontoeachstateLabel.Text = 'Type in rates of transition to each state';
            app.TypeintheinitialnumberofpeopleineachstateLabel = uilabel(app.ZarzdzanieTab);
            app.TypeintheinitialnumberofpeopleineachstateLabel.Text = 'Type in the initial number of people in each state';
            app.NosecuritymeasuresLabel = uilabel(app.ZarzdzanieTab);
            app.NosecuritymeasuresLabel.Text = 'No security measures';
            app.NSM0 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.SelfprotectingLabel_2 = uilabel(app.ZarzdzanieTab);
            app.SelfprotectingLabel_2.Text = 'Self-protecting';
            app.SP0 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.InfectedLabel_2 = uilabel(app.ZarzdzanieTab);
            app.InfectedLabel_2.Text = 'Infected ';
            app.INF0 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.INF0.Value = 200;
            app.InfectedandsickLabel_2 = uilabel(app.ZarzdzanieTab);
            app.InfectedandsickLabel_2.Text = 'Infected and sick';
            app.IAS0 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.IAS0.Value = 100;
            app.RecoveredLabel = uilabel(app.ZarzdzanieTab);
            app.RecoveredLabel.Text = 'Recovered';
            app.REC0 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.REC0.Value = 10;
            app.QuarantineLabel = uilabel(app.ZarzdzanieTab);
            app.QuarantineLabel.Text = 'Quarantine';
            app.Q0 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.Q0.Value = 200;
            app.InhospitalLabel = uilabel(app.ZarzdzanieTab);
            app.InhospitalLabel.Text = 'In hospital';
            app.INH0 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.INH0.Value = 100;
            app.DeadLabel = uilabel(app.ZarzdzanieTab);
            app.DeadLabel.Text = 'Dead';
            app.D0 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.HealthyNosecuritymeasuresLabel = uilabel(app.ZarzdzanieTab);
            app.HealthyNosecuritymeasuresLabel.Text = 'Healthy - No security measures';
            app.HealthySelfprotectingLabel = uilabel(app.ZarzdzanieTab);
            app.HealthySelfprotectingLabel.Text = 'Healthy - Self-protecting';
            app.SelfprotectingInfectedLabel = uilabel(app.ZarzdzanieTab);
            app.SelfprotectingInfectedLabel.Text = 'Self-protecting - Infected';
            app.NosecuritymeasuresInfectedLabel = uilabel(app.ZarzdzanieTab);
            app.NosecuritymeasuresInfectedLabel.Text = 'No security measures - Infected';
            app.InfectedQuarantineLabel = uilabel(app.ZarzdzanieTab);
            app.InfectedQuarantineLabel.Text = 'Infected - Quarantine';
            app.InfectedInfectedandsickLabel = uilabel(app.ZarzdzanieTab);
            app.InfectedInfectedandsickLabel.Text = 'Infected - Infected and sick';
            app.InfectedandsickQuarantineLabel = uilabel(app.ZarzdzanieTab);
            app.InfectedandsickQuarantineLabel.Text = 'Infected and sick - Quarantine';
            app.InfectedandsickInhospitalLabel = uilabel(app.ZarzdzanieTab);
            app.InfectedandsickInhospitalLabel.Text = 'Infected and sick - In hospital';
            app.InhospitalRecoveredLabel = uilabel(app.ZarzdzanieTab);
            app.InhospitalRecoveredLabel.Text = 'In hospital - Recovered';
            app.QuarantineRecoveredLabel = uilabel(app.ZarzdzanieTab);
            app.QuarantineRecoveredLabel.Text = 'Quarantine - Recovered';
            app.InhospitalDeadLabel = uilabel(app.ZarzdzanieTab);
            app.InhospitalDeadLabel.Text = 'In hospital - Dead';
            app.TypeinpopulationLabel = uilabel(app.ZarzdzanieTab);
            app.TypeinpopulationLabel.Text = 'Type in population ';
            app.populationModel = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.populationModel.Value = 5368000;
            app.l1 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.l1.Value = 0.01;
            app.l2 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.l2.Value = 0.1;
            app.l3 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.l3.Value = 100;
            app.o2 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.o2.Value = 0.1;
            app.o3 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.o3.Value = 100;
            app.f2 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.f2.Value = 0.01;
            app.f3 = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.f3.Value = 10;
            app.TestrateLabel_2 = uilabel(app.ZarzdzanieTab);
            app.TestrateLabel_2.Text = 'Test rate [%]';
            app.ModelTestRate = uieditfield(app.ZarzdzanieTab, 'numeric');
            app.ModelTestRate.Value = 50;
            app.TypeintestrateLabel = uilabel(app.ZarzdzanieTab);
            app.TypeintestrateLabel.Text = 'Type in test rate';
            app.WyczywykresyButton = uibutton(app.ZarzdzanieTab, 'push');
            app.WyczywykresyButton.ButtonPushedFcn = createCallbackFcn(app, @clearModelAxes, true);
            app.WyczywykresyButton.Text = 'Wyczyść wykresy';
            app.ModelWykresy = uitab(app.TabGroup4);
            app.ModelWykresy.Title = 'Wykresy';
            app.TabGroup5 = uitabgroup(app.ModelWykresy);
            app.ModelTabNSM = uitab(app.TabGroup5);
            app.ModelTabNSM.Title = 'No security measures';
            app.AxesModelNSM = uiaxes(app.ModelTabNSM);
            title(app.AxesModelNSM, 'No security measures')
            xlabel(app.AxesModelNSM, 'Iteration')
            ylabel(app.AxesModelNSM, '')
            app.AxesModelNSM.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ModelTabSP = uitab(app.TabGroup5);
            app.ModelTabSP.Title = 'Self protecting';
            app.AxesModelSP = uiaxes(app.ModelTabSP);
            title(app.AxesModelSP, 'Self protecting')
            xlabel(app.AxesModelSP, 'Iteration')
            ylabel(app.AxesModelSP, '')
            app.ModelTabINF = uitab(app.TabGroup5);
            app.ModelTabINF.Title = 'Infected';
            app.AxesModelINF = uiaxes(app.ModelTabINF);
            title(app.AxesModelINF, 'Infected')
            xlabel(app.AxesModelINF, 'Iteration')
            ylabel(app.AxesModelINF, '')
            app.ModelTabIAS = uitab(app.TabGroup5);
            app.ModelTabIAS.Title = 'Infected and sick';
            app.AxesModelIAS = uiaxes(app.ModelTabIAS);
            title(app.AxesModelIAS, 'Infected and sick')
            xlabel(app.AxesModelIAS, 'Iteration')
            ylabel(app.AxesModelIAS, '')
            app.ModelTabQ = uitab(app.TabGroup5);
            app.ModelTabQ.Title = 'In quarantine';
            app.AxesModelQ = uiaxes(app.ModelTabQ);
            title(app.AxesModelQ, 'In quarantine')
            xlabel(app.AxesModelQ, 'Iteration')
            ylabel(app.AxesModelQ, '')
            app.ModelTabINH = uitab(app.TabGroup5);
            app.ModelTabINH.Title = 'In hospital';
            app.AxesModelINH = uiaxes(app.ModelTabINH);
            title(app.AxesModelINH, 'In hospital')
            xlabel(app.AxesModelINH, 'Iteration')
            ylabel(app.AxesModelINH, '')
            app.ModelTabTotalINF = uitab(app.TabGroup5);
            app.ModelTabTotalINF.Title = 'Total infected';
            app.AxesModelTotalINF = uiaxes(app.ModelTabTotalINF);
            title(app.AxesModelTotalINF, 'Total infected')
            xlabel(app.AxesModelTotalINF, 'Iteration')
            ylabel(app.AxesModelTotalINF, '')
            app.ModelTabREC = uitab(app.TabGroup5);
            app.ModelTabREC.Title = 'Recovered';
            app.AxesModelREC = uiaxes(app.ModelTabREC);
            title(app.AxesModelREC, 'Recovered')
            xlabel(app.AxesModelREC, 'Iteration')
            ylabel(app.AxesModelREC, '')
            app.ModelTabD = uitab(app.TabGroup5);
            app.ModelTabD.Title = 'Dead';
            app.AxesModelD = uiaxes(app.ModelTabD);
            title(app.AxesModelD, 'Dead')
            xlabel(app.AxesModelD, 'Iteration')
            ylabel(app.AxesModelD, '')
            app.SymulacjeTab = uitab(app.TabGroup);
            app.SymulacjeTab.Title = 'Symulacje';
            app.TabGroup2 = uitabgroup(app.SymulacjeTab);
            app.NorwegiaTab = uitab(app.TabGroup2);
            app.NorwegiaTab.Title = 'Norwegia';
            app.PlaneSizeEditFieldLabel = uilabel(app.NorwegiaTab);
            app.PlaneSizeEditFieldLabel.Text = 'PlaneSize';
            app.PlaneSizeEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.PlaneSizeEditField.Value = 128;
            app.NumberOfSimulationsEditFieldLabel = uilabel(app.NorwegiaTab);
            app.NumberOfSimulationsEditFieldLabel.Text = 'NumberOfSimulations';
            app.NumberOfSimulationsEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.NumberOfSimulationsEditField.Value = 4;
            app.NumberOfSimulationStepsEditFieldLabel = uilabel(app.NorwegiaTab);
            app.NumberOfSimulationStepsEditFieldLabel.Text = 'NumberOfSimulationSteps';
            app.NumberOfSimulationStepsEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.NumberOfSimulationStepsEditField.Value = 256;
            app.ChanceToGetSickEditFieldLabel = uilabel(app.NorwegiaTab);
            app.ChanceToGetSickEditFieldLabel.Text = 'ChanceToGetSick';
            app.ChanceToGetSickEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.ChanceToGetSickEditField.Value = 0.75;
            app.AverageIncubationEditFieldLabel = uilabel(app.NorwegiaTab);
            app.AverageIncubationEditFieldLabel.Text = 'AverageIncubation';
            app.AverageIncubationEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.AverageIncubationEditField.Value = 7;
            app.SigmaIncubationEditFieldLabel = uilabel(app.NorwegiaTab);
            app.SigmaIncubationEditFieldLabel.Text = 'SigmaIncubation';
            app.SigmaIncubationEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.SigmaIncubationEditField.Value = 5;
            app.AverageDamageEditFieldLabel = uilabel(app.NorwegiaTab);
            app.AverageDamageEditFieldLabel.Text = 'AverageDamage';
            app.AverageDamageEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.AverageDamageEditField.Value = 2;
            app.SigmaDamageEditFieldLabel = uilabel(app.NorwegiaTab);
            app.SigmaDamageEditFieldLabel.Text = 'SigmaDamage';
            app.SigmaDamageEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.SigmaDamageEditField.Value = 3;
            app.StartHealthEditFieldLabel = uilabel(app.NorwegiaTab);
            app.StartHealthEditFieldLabel.Text = 'StartHealth';
            app.StartHealthEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.StartHealthEditField.Value = 10;
            app.HealthCareLimitEditFieldLabel = uilabel(app.NorwegiaTab);
            app.HealthCareLimitEditFieldLabel.Text = 'HealthCareLimit';
            app.HealthCareLimitEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.HealthCareLimitEditField.Value = 0.1;
            app.ChanceToGoToHospitalByYourselfEditFieldLabel = uilabel(app.NorwegiaTab);
            app.ChanceToGoToHospitalByYourselfEditFieldLabel.Text = 'ChanceToGoToHospitalByYourself';
            app.ChanceToGoToHospitalByYourselfEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.ChanceToGoToHospitalByYourselfEditField.Value = 0.05;
            app.StartTestedProcentEditFieldLabel = uilabel(app.NorwegiaTab);
            app.StartTestedProcentEditFieldLabel.Text = 'StartTestedProcent';
            app.StartTestedProcentEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.StartTestedProcentEditField.Value = 0.002;
            app.TestIncomPerDayEditFieldLabel = uilabel(app.NorwegiaTab);
            app.TestIncomPerDayEditFieldLabel.Text = 'TestIncomPerDay';
            app.TestIncomPerDayEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.TestIncomPerDayEditField.Value = 0.002;
            app.TestSensitivityEditFieldLabel = uilabel(app.NorwegiaTab);
            app.TestSensitivityEditFieldLabel.Text = 'TestSensitivity';
            app.TestSensitivityEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.TestSensitivityEditField.Value = 0.75;
            app.ProtectingMultEditFieldLabel = uilabel(app.NorwegiaTab);
            app.ProtectingMultEditFieldLabel.Text = 'ProtectingMult';
            app.ProtectingMultEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.ProtectingMultEditField.Value = 0.0001;
            app.OrganizingMultEditFieldLabel = uilabel(app.NorwegiaTab);
            app.OrganizingMultEditFieldLabel.Text = 'OrganizingMult';
            app.OrganizingMultEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.OrganizingMultEditField.Value = 0.0005;
            app.ChanceToGetSickAgainMulEditFieldLabel = uilabel(app.NorwegiaTab);
            app.ChanceToGetSickAgainMulEditFieldLabel.Text = 'ChanceToGetSickAgainMul';
            app.ChanceToGetSickAgainMulEditField = uieditfield(app.NorwegiaTab, 'numeric');
            app.ChanceToGetSickAgainMulEditField.Value = 0.5;
            app.RunSimulationButton = uibutton(app.NorwegiaTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'RunSimulation';
            app.FrancjaTab = uitab(app.TabGroup2);
            app.FrancjaTab.Title = 'Francja';
            app.TabGroup3 = uitabgroup(app.FrancjaTab);
            app.ParametrysymulacjiTab = uitab(app.TabGroup3);
            app.ParametrysymulacjiTab.Title = 'Parametry symulacji';
            app.SizeEditFieldLabel = uilabel(app.ParametrysymulacjiTab);
            app.SizeEditFieldLabel.Text = 'Size';
            app.FranceSize = uieditfield(app.ParametrysymulacjiTab, 'numeric');
            app.FranceSize.Value = 100;
            app.DaysEditFieldLabel = uilabel(app.ParametrysymulacjiTab);
            app.DaysEditFieldLabel.Text = 'Days';
            app.FranceDays = uieditfield(app.ParametrysymulacjiTab, 'numeric');
            app.FranceDays.ValueChangedFcn = createCallbackFcn(app, @runModelSim, true);
            app.FranceDays.Value = 30;
            app.TestrateLabel = uilabel(app.ParametrysymulacjiTab);
            app.TestrateLabel.Text = 'Test rate [%]';
            app.FranceTestRate = uieditfield(app.ParametrysymulacjiTab, 'numeric');
            app.FranceTestRate.Value = 50;
            app.FranceStartSimulation = uibutton(app.ParametrysymulacjiTab, 'push');
            app.FranceStartSimulation.ButtonPushedFcn = createCallbackFcn(app, @runSimFrance, true);
            app.FranceStartSimulation.Text = 'Start simulation';
            app.ProbabilityofinfectionLabel = uilabel(app.ParametrysymulacjiTab);
            app.ProbabilityofinfectionLabel.Text = 'Probability of infection [%]';
            app.FranceProbabilityOfInfection = uieditfield(app.ParametrysymulacjiTab, 'numeric');
            app.FranceProbabilityOfInfection.Value = 5;
            app.NumberofPatientsZeroEditFieldLabel = uilabel(app.ParametrysymulacjiTab);
            app.NumberofPatientsZeroEditFieldLabel.Text = 'Number of ''Patients Zero''';
            app.FranceNumberOfPatientsZero = uieditfield(app.ParametrysymulacjiTab, 'numeric');
            app.FranceNumberOfPatientsZero.Value = 10;
            app.SeriouscasesEditFieldLabel = uilabel(app.ParametrysymulacjiTab);
            app.SeriouscasesEditFieldLabel.Text = 'Serious cases [%]';
            app.FranceSeriousCases = uieditfield(app.ParametrysymulacjiTab, 'numeric');
            app.FranceSeriousCases.Value = 5;
            app.AveragefatalityrateEditFieldLabel = uilabel(app.ParametrysymulacjiTab);
            app.AveragefatalityrateEditFieldLabel.Text = 'Average fatality rate [%]';
            app.FranceFatalityRate = uieditfield(app.ParametrysymulacjiTab, 'numeric');
            app.FranceFatalityRate.Value = 1;
            app.FranceStop = uicheckbox(app.ParametrysymulacjiTab);
            app.FranceStop.Text = 'Request simulation stop';
            app.FranceClear = uibutton(app.ParametrysymulacjiTab, 'push');
            app.FranceClear.ButtonPushedFcn = createCallbackFcn(app, @FranceClearAreas, true);
            app.FranceClear.Text = 'Clear';
            app.Q1Tab = uitab(app.TabGroup3);
            app.Q1Tab.Title = 'Q1';
            app.AxesFranceQ1 = uiaxes(app.Q1Tab);
            title(app.AxesFranceQ1, '')
            xlabel(app.AxesFranceQ1, '')
            ylabel(app.AxesFranceQ1, '')
            app.AxesFranceQ1.XTick = [];
            app.AxesFranceQ1.YTick = [];
            app.Q2Tab = uitab(app.TabGroup3);
            app.Q2Tab.Title = 'Q2';
            app.AxesFranceQ2 = uiaxes(app.Q2Tab);
            title(app.AxesFranceQ2, '')
            xlabel(app.AxesFranceQ2, '')
            ylabel(app.AxesFranceQ2, '')
            app.AxesFranceQ2.XTick = [];
            app.AxesFranceQ2.YTick = [];
            app.WykresyTab = uitab(app.TabGroup3);
            app.WykresyTab.Title = 'Wykresy';
            app.AxesFrance1 = uiaxes(app.WykresyTab);
            title(app.AxesFrance1, 'New infected')
            xlabel(app.AxesFrance1, 'Day')
            ylabel(app.AxesFrance1, '')
            app.AxesFrance2 = uiaxes(app.WykresyTab);
            title(app.AxesFrance2, 'Total infected')
            xlabel(app.AxesFrance2, 'Day')
            ylabel(app.AxesFrance2, '')
            app.AxesFrance3 = uiaxes(app.WykresyTab);
            title(app.AxesFrance3, 'Other informations')
            xlabel(app.AxesFrance3, 'Day')
            ylabel(app.AxesFrance3, '')
            app.AxesFrance4 = uiaxes(app.WykresyTab);
            title(app.AxesFrance4, 'In hospital')
            xlabel(app.AxesFrance4, 'Day')
            ylabel(app.AxesFrance4, '')
            app.AustraliaTab = uitab(app.TabGroup2);
            app.AustraliaTab.Title = 'Australia';
            app.StartButtonAustralia = uibutton(app.AustraliaTab, 'push');
            app.StartButtonAustralia.ButtonPushedFcn = createCallbackFcn(app, @StartButtonAustraliaPushed, true);
            app.StartButtonAustralia.Text = 'Start ';
            app.SizeofplaneLabel = uilabel(app.AustraliaTab);
            app.SizeofplaneLabel.Text = 'Size of plane';
            app.SizeEditAustralia = uieditfield(app.AustraliaTab, 'numeric');
            app.SizeEditAustralia.Value = 100;
            app.DaysEditFieldLabel_2 = uilabel(app.AustraliaTab);
            app.DaysEditFieldLabel_2.Text = 'Days';
            app.DaysEditAustralia = uieditfield(app.AustraliaTab, 'numeric');
            app.DaysEditAustralia.Value = 30;
            app.AxesAustralia = uiaxes(app.AustraliaTab);
            title(app.AxesAustralia, '')
            xlabel(app.AxesAustralia, '')
            ylabel(app.AxesAustralia, '')
            app.TypeinLabel = uilabel(app.AustraliaTab);
            app.TypeinLabel.Text = 'Type in';
            app.madeTests = uieditfield(app.AustraliaTab, 'numeric');
            app.ResultsLabel = uilabel(app.AustraliaTab);
            app.ResultsLabel.Text = 'Results';
            app.NumberodtestsdoneLabel = uilabel(app.AustraliaTab);
            app.NumberodtestsdoneLabel.Text = 'Number od tests done';
            app.NumberofpositivetestsLabel = uilabel(app.AustraliaTab);
            app.NumberofpositivetestsLabel.Text = 'Number of positive tests';
            app.positiveTests = uieditfield(app.AustraliaTab, 'numeric');
            app.NumberoffallinfectedpeopleLabel = uilabel(app.AustraliaTab);
            app.NumberoffallinfectedpeopleLabel.Text = 'Number off all infected people';
            app.numInfected = uieditfield(app.AustraliaTab, 'numeric');
            app.numRec = uieditfield(app.AustraliaTab, 'numeric');
            app.numDead = uieditfield(app.AustraliaTab, 'numeric');
            app.NumberofrecoveredpeopleLabel = uilabel(app.AustraliaTab);
            app.NumberofrecoveredpeopleLabel.Text = 'Number of recovered people';
            app.NumberofdeadpeopleLabel = uilabel(app.AustraliaTab);
            app.NumberofdeadpeopleLabel.Text = 'Number of dead people';
            app.USATab = uitab(app.TabGroup2);
            app.USATab.Title = 'USA';
            app.TabGroup3_3 = uitabgroup(app.USATab);
            app.MainstatsTab = uitab(app.TabGroup3_3);
            app.MainstatsTab.Title = 'Main stats';
            app.StartButton = uibutton(app.MainstatsTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LiczbacykliUSAEditFieldLabel = uilabel(app.MainstatsTab);
            app.LiczbacykliUSAEditFieldLabel.Text = 'Liczba cykli USA:';
            app.LiczbacykliUSAEditField = uieditfield(app.MainstatsTab, 'numeric');
            app.ZdrowiLabel = uilabel(app.MainstatsTab);
            app.ZdrowiLabel.Text = 'Zdrowi:';
            app.ZdrowiEditField = uieditfield(app.MainstatsTab, 'numeric');
            app.ChorzyEditFieldLabel = uilabel(app.MainstatsTab);
            app.ChorzyEditFieldLabel.Text = 'Chorzy:';
            app.ChorzyEditField = uieditfield(app.MainstatsTab, 'numeric');
            app.WyzdrowialiEditFieldLabel = uilabel(app.MainstatsTab);
            app.WyzdrowialiEditFieldLabel.Text = 'Wyzdrowiali:';
            app.WyzdrowialiEditField = uieditfield(app.MainstatsTab, 'numeric');
            app.ZmarliEditFieldLabel = uilabel(app.MainstatsTab);
            app.ZmarliEditFieldLabel.Text = 'Zmarli:';
            app.ZmarliEditField = uieditfield(app.MainstatsTab, 'numeric');
            app.ACTIVECASESLabel_2 = uilabel(app.MainstatsTab);
            app.ACTIVECASESLabel_2.Text = 'ACTIVE CASES';
            app.HEALTHLabel_2 = uilabel(app.MainstatsTab);
            app.HEALTHLabel_2.Text = 'HEALTH';
            app.RECOVEREDLabel_2 = uilabel(app.MainstatsTab);
            app.RECOVEREDLabel_2.Text = 'RECOVERED';
            app.DEADLabel_2 = uilabel(app.MainstatsTab);
            app.DEADLabel_2.Text = 'DEAD';
            app.UITable = uitable(app.MainstatsTab);
            app.UITable.ColumnName = {'Alabama'; 'Arizona'; 'Arkansas'; 'California'; 'Colorado'; 'Connecticut'; 'Delaware'; 'Florida'; ''; 'Idaho'; 'Illinois'; 'Indiana'; 'Iowa'; 'Kansas'; 'Kentucky'; 'Louisiana'; 'Maine'; 'Meryland'; 'Massachussets'; 'Michigan'; 'Minnesotta'; 'Mississippi'; 'Missouri'; 'Montana'; 'Nebraska'; 'Nevada'; 'New Hampshire'; 'New Jersey'; 'New Mexico'; 'New York'; 'North Carolina'; 'North Dakota'; 'Ohio'; 'Oklahoma'; 'Oregon'; 'Pennsylvania'; 'Rhode Island'; 'South Carolina'; 'South Dakota'; 'Tennessee'; 'Texas'; 'Utah'; 'Vermont'; 'Virginia'; 'Washington'; 'West Virginia'; 'Wisconsin'; 'Wyoming'};
            app.UITable.RowName = {};
            app.PorwnaniemodeliNorwegiaTab = uitab(app.TabGroup);
            app.PorwnaniemodeliNorwegiaTab.Title = 'Porównanie modeli (Norwegia)';
            app.TabGroup3_2 = uitabgroup(app.PorwnaniemodeliNorwegiaTab);
            app.RecoveredTab_2 = uitab(app.TabGroup3_2);
            app.RecoveredTab_2.Title = 'Recovered';
            app.NorwayLabel = uilabel(app.RecoveredTab_2);
            app.NorwayLabel.Text = 'Norway';
            app.CAModelLabel = uilabel(app.RecoveredTab_2);
            app.CAModelLabel.Text = 'CA Model';
            app.DifferentialModelLabel = uilabel(app.RecoveredTab_2);
            app.DifferentialModelLabel.Text = 'Differential Model';
            app.CArecnum = uiaxes(app.RecoveredTab_2);
            title(app.CArecnum, 'Title')
            xlabel(app.CArecnum, 'X')
            ylabel(app.CArecnum, 'Y')
            app.Diffrecnum = uiaxes(app.RecoveredTab_2);
            title(app.Diffrecnum, 'Title')
            xlabel(app.Diffrecnum, 'X')
            ylabel(app.Diffrecnum, 'Y')
            app.DeathsTab = uitab(app.TabGroup3_2);
            app.DeathsTab.Title = 'Deaths';
            app.NorwayLabel_2 = uilabel(app.DeathsTab);
            app.NorwayLabel_2.Text = 'Norway';
            app.CAModelLabel_2 = uilabel(app.DeathsTab);
            app.CAModelLabel_2.Text = 'CA Model';
            app.DifferentialModelLabel_2 = uilabel(app.DeathsTab);
            app.DifferentialModelLabel_2.Text = 'Differential Model';
            app.CAdeathsnum = uiaxes(app.DeathsTab);
            title(app.CAdeathsnum, 'Title')
            xlabel(app.CAdeathsnum, 'X')
            ylabel(app.CAdeathsnum, 'Y')
            app.Diffdeathsnum = uiaxes(app.DeathsTab);
            title(app.Diffdeathsnum, 'Title')
            xlabel(app.Diffdeathsnum, 'X')
            ylabel(app.Diffdeathsnum, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TADSISPD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end